classdef Practica1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ValoresSliderLabel             matlab.ui.control.Label
        ValoresSlider                  matlab.ui.control.Slider
        SealesListBoxLabel             matlab.ui.control.Label
        SealesListBox                  matlab.ui.control.ListBox
        ColordelagraficaDropDownLabel  matlab.ui.control.Label
        ColordelagraficaDropDown       matlab.ui.control.DropDown
        Image                          matlab.ui.control.Image
        EditField                      matlab.ui.control.EditField
        UnirButton                     matlab.ui.control.Button
        ValoresSlider_2                matlab.ui.control.Slider
        EditField_2                    matlab.ui.control.EditField
        ColordelagraficaDropDown_2     matlab.ui.control.DropDown
        EditField_3                    matlab.ui.control.EditField
        GraficarButton                 matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Titulo % Cambia el titulo de la grafica 
        Valor % Description
        Grafica % Description
        btn % Description
        Grafica2            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ValoresSlider
        function ValoresSliderValueChanged(app, event)
            value = app.ValoresSlider.Value;
            app.Valor=value;
            switch app.SealesListBox.Value
                case 'Mariposa Monarca'
                    y=[6.23 7.81 12.61 18.19 5.77 5.56 8.97 3.83 9.36 7.54 11.12 2.19 5.91 6.87 4.61 5.06 1.92 4.02 2.89 1.19 0.67 1.15 4.01 2.91 2.48 6.05 2.83 2.1 2.84];
                    x=floor(value);
                    for i=1:x
                        z(i)=y(i);
                    end
                    plot(app.UIAxes,z,"color",app.Grafica.Color);
                    app.UIAxes.Title.String="Ocupación historica de la mariposa Monarca";
                    ylabel(app.UIAxes,"Area Ocupada(hectareas)");
                    xlabel(app.UIAxes,"Años desde 1993-2022");
                case 'Corazon'
                     aux=1;
                     y=[1 1 2 -3 6 -5 4 1 1 1 ];
                     for i=1:value
                         for j=1:10
                             x(aux)=y(j);
                             aux=aux+1;
                         end
                     end
                     plot(app.UIAxes,x,"color",app.Grafica.Color);
                     app.UIAxes.Title.String="Frecuencia cardiaca en latidos por minuto";
                case 'Sonido'
                       x=0:0.001:2*pi;
                       y=sin(value*x);
                       plot(app.UIAxes,y,"color",app.Grafica.Color);
                case 'Temperatura'
                       y=[13.68 13.67 13.59 13.64 13.76 13.89 13.95 13.92 13.93 13.95 14.12 14.26 14.47];
                       x=floor(value);
                    for i=1:x
                        z(i)=y(i);
                    end
                        plot(app.UIAxes,z,"color",app.Grafica.Color);
                case 'Aceleracion'
                        if(app.btn==0)
                            d=0:1:100;
                            v=d/value;
                            plot(app.UIAxes,v,"color",app.Grafica.Color);
                        end
                        
                case 'Aceleracion Elefante'
                        if(app.btn==0)
                            d=0:1:100;
                            v=d/value;
                            plot(app.UIAxes,v,'color',app.Grafica.Color);
                        end
                case 'Ruido'
                        ValorAle=rand(1,value);
                        plot(app.UIAxes,ValorAle,'color',app.Grafica.Color);
                case 'Aumento del nivel del mar'
                        y=[12.5 22.6 38.8 53.1 72.5];
                        x=[1995 2000 2005 2010 2015];
                        for i=1:floor(value)
                            x1(i)=x(i);
                            y1(i)=y(i);
                        end
                        plot(app.UIAxes,x1,y1,"color",app.Grafica.Color);
                        ylabel(app.UIAxes,"Variacion de la altura del mar(mm)");
                        xlabel(app.UIAxes,"Años");
                case 'Radiacion de una temperatura'
                            A=0:1e-8:3e-6
                            T=value;
                            for i=1:1:301
                                Ecu(1,i)=(8*pi*6.6238e-34)/(A(1,i)^5*(exp(((6.6238e-34*3e8)/(A(1,i)*1.3805e-23*T)))-1));
                            end
                            plot(app.UIAxes,A,Ecu,'color',app.Grafica.Color);
                            ylabel(app.UIAxes,"Energía Radiada W/m^2");
                            xlabel(app.UIAxes,"Longitud de onda");
                otherwise
            end
        end

        % Value changed function: SealesListBox
        function SealesListBoxValueChanged(app, event)
            value = app.SealesListBox.Value;
            app.Titulo=value;
            cla(app.UIAxes,'reset');
            switch value
                case 'Mariposa Monarca'
                    app.UIAxes.Title.String="Ocupación historica de la mariposa Monarca";
                    app.ValoresSlider.Limits=[2 29];
                    app.ValoresSliderLabel.Text="Años";
                    ylabel(app.UIAxes,"Area Ocupada(hectareas)");
                    xlabel(app.UIAxes,"Años desde 1993-2022");
                    app.Image.ImageSource="Mariposa.jpg";
                    xlim(app.UIAxes,[-inf inf]);
                    ylim(app.UIAxes,[-inf inf]);
                    app.UnirButton.Enable=false;
                    app.UnirButton.Visible=false;
                case 'Corazon'
                    app.UIAxes.Title.String="Frecuencia cardiaca en latidos por minuto";
                    app.ValoresSlider.Limits=[1 10];
                    app.ValoresSliderLabel.Text="Latidos(escala 1:10)";
                    ylabel(app.UIAxes,"volts");
                    xlabel(app.UIAxes,"Latidos");
                    app.Image.ImageSource="Corazon.jpg";
                    xlim(app.UIAxes,[0 inf]);
                    ylim(app.UIAxes,[-5.5 6.5]);
                    app.UnirButton.Enable=false;
                    app.UnirButton.Visible=false;
                case 'Sonido'
                    app.UIAxes.Title.String="Representacion del sonido";
                    app.ValoresSlider.Limits=[0 20];
                    app.ValoresSliderLabel.Text="Frecuencia(Hz)";
                    ylabel(app.UIAxes,"Amplitud");
                    xlabel(app.UIAxes,"Time(1/63miliseg)");
                    app.Image.ImageSource="Sonido.jpg";
                    xlim(app.UIAxes,[-inf inf]);
                    ylim(app.UIAxes,[-inf inf]);
                    app.UnirButton.Enable=false;
                    app.UnirButton.Visible=false;
                case 'Temperatura'
                    app.UIAxes.Title.String="Calentamiento global atravez de las decadas ";
                    app.ValoresSlider.Limits=[1 13];
                    app.ValoresSliderLabel.Text="Decadas";
                    ylabel(app.UIAxes,"Temperatura Global(°C)");
                    xlabel(app.UIAxes,"Decadas 1880-2010");
                    app.Image.ImageSource="Calentamiento.jpg";
                    xlim(app.UIAxes,[-inf inf]);
                    ylim(app.UIAxes,[-inf inf]);
                    app.UnirButton.Enable=false;
                    app.UnirButton.Visible=false;
                case 'Aceleracion'
                    app.UIAxes.Title.String="Aceleracion de un humano ";
                    app.ValoresSlider.Limits=[9.58 15];
                    app.ValoresSlider_2.Limits=[9 14];
                    app.ValoresSliderLabel.Text="Tiempo";
                    ylabel(app.UIAxes,"Velocidad(m/s)");
                    xlabel(app.UIAxes,"Distancia(m)");
                    app.Image.ImageSource="Aceleracion.jpg";
                    xlim(app.UIAxes,[0 100]);
                    ylim(app.UIAxes,[0 15]);
                    app.UnirButton.Enable=true;
                    app.UnirButton.Visible=true;
                case 'Aceleracion Elefante'
                    app.UIAxes.Title.String="Aceleracion de un Elefante ";
                    app.ValoresSlider.Limits=[9 14];
                    app.ValoresSlider_2.Limits=[9.58 15];
                    app.ValoresSliderLabel.Text="Tiempo";
                    ylabel(app.UIAxes,"Velocidad(m/s)");
                    xlabel(app.UIAxes,"Distancia(m)");
                    app.Image.ImageSource="Elefante.jpg";
                    xlim(app.UIAxes,[0 100]);
                    ylim(app.UIAxes,[0 15]);  
                    app.UnirButton.Enable=true;
                    app.UnirButton.Visible=true;
                case 'Ruido'
                    app.UIAxes.Title.String="Ruido en la naturaleza ";
                    app.ValoresSlider.Limits=[100 1000];
                    app.ValoresSliderLabel.Text="Variable Aleatoria";
                    ylabel(app.UIAxes,"No de datos");
                    xlabel(app.UIAxes,"Onda");
                    app.Image.ImageSource="venado.jpg";
                    xlim(app.UIAxes,[-inf inf]);
                    ylim(app.UIAxes,[-inf inf]);  
                    app.UnirButton.Enable=false;
                    app.UnirButton.Visible=false;
                case 'Aumento del nivel del mar'
                    app.UIAxes.Title.String="aumento del nivel del mar";
                    app.ValoresSlider.Limits=[1 5];
                    app.ValoresSliderLabel.Text="lustros";
                    ylabel(app.UIAxes,"Variacion de la altura del mar(mm)");
                    xlabel(app.UIAxes,"Años");
                    app.Image.ImageSource="mar.jpg";
                    xlim(app.UIAxes,[1995 2020]);
                    ylim(app.UIAxes,[10 70]);  
                    app.UnirButton.Enable=false;
                    app.UnirButton.Visible=false;
                case 'Radiacion de una temperatura'
                    app.UIAxes.Title.String="Radiacion de una temperatura dada";
                    app.ValoresSlider.Limits=[4000 6000];
                    app.ValoresSliderLabel.Text="Temperatura Kelvins";
                    ylabel(app.UIAxes,"Energía Radiada W/m^2");
                    xlabel(app.UIAxes,"Longitud de onda");
                    app.Image.ImageSource="Radiacion.jpg";
                    xlim(app.UIAxes,[1995 2020]);
                    ylim(app.UIAxes,[10 70]);  
                    app.UnirButton.Enable=false;
                    app.UnirButton.Visible=false;
                case 'Tangente en una señal'
                    app.UIAxes.Title.String="Tangente de una señal";
                    app.ValoresSlider.Limits=[0 2*pi];
                    app.ValoresSliderLabel.Text="Valores";
                    ylabel(app.UIAxes,"Magnitud");
                    xlabel(app.UIAxes,"Grados");
                    app.Image.ImageSource="tangente.jpg";
                    xlim(app.UIAxes,[-inf inf]);
                    ylim(app.UIAxes,[-inf inf]);  
                    app.UnirButton.Enable=false;
                    app.UnirButton.Visible=false;
                otherwise
                    
            end
            app.ValoresSlider_2.Enable=false;
            app.ValoresSlider_2.Visible=false;
            app.EditField_2.Visible=false;
            app.EditField_2.Enable=false;
            app.EditField_3.Visible=false;
            app.EditField_3.Enable=false;
            app.ColordelagraficaDropDown_2.Visible=false;
            app.ColordelagraficaDropDown_2.Enable=false;
            app.GraficarButton.Visible=false;
            app.GraficarButton.Enable=false;
            app.btn=0;
        end

        % Value changed function: ColordelagraficaDropDown
        function ColordelagraficaDropDownValueChanged(app, event)
            value = app.ColordelagraficaDropDown.Value;
            switch value
                case 'Azul'
                    app.Grafica.Color=[0 0 1];
                case 'Rojo'
                    app.Grafica.Color=[1 0 0];
                case 'Verde'
                    app.Grafica.Color=[0.09 0.63 0];
                case 'Morado'
                    app.Grafica.Color=[0.5 0 1];
                case "Negro"
                    app.Grafica.Color=[0 0 0];
                case "Naranja"
                    app.Grafica.Color=[1 0.4 0];
                otherwise
                    app.Grafica.Color=[1 0 0.7];
            end
        end

        % Button pushed function: UnirButton
        function UnirButtonPushed(app, event)
        
        switch app.SealesListBox.Value
            case 'Aceleracion'
                app.UIAxes.Title.String="Aceleracion humano vs Elefante ";
                app.Image.ImageSource="humele.jpg";
            case 'Aceleracion Elefante'
                app.UIAxes.Title.String="Aceleracion humano vs Elefante ";
                app.Image.ImageSource="humele.jpg";
            otherwise
        end
            app.btn=1;
            app.ValoresSlider_2.Enable=true;
            app.ValoresSlider_2.Visible=true;
            app.EditField_2.Visible=true;
            app.EditField_2.Enable=true;
            app.EditField_3.Visible=true;
            app.EditField_3.Enable=true;
            app.ColordelagraficaDropDown_2.Visible=true;
            app.ColordelagraficaDropDown_2.Enable=true;
            app.GraficarButton.Visible=true;
            app.GraficarButton.Enable=true;
        end

        % Value changed function: ColordelagraficaDropDown_2
        function ColordelagraficaDropDown_2ValueChanged(app, event)
            value = app.ColordelagraficaDropDown_2.Value;
            switch value
                case 'Azul'
                    app.Grafica2.Color=[0 0 1];
                case 'Rojo'
                    app.Grafica2.Color=[1 0 0];
                case 'Verde'
                    app.Grafica2.Color=[0.09 0.63 0];
                case 'Morado'
                    app.Grafica2.Color=[0.5 0 1];
                case "Negro"
                    app.Grafica2.Color=[0 0 0];
                case "Naranja"
                    app.Grafica2.Color=[1 0.4 0];
                otherwise
                    app.Grafica2.Color=[1 0 0.7];
            end
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            
             switch app.SealesListBox.Value
                case 'Aceleracion'
                        d=0:1:100;
                        v=d/app.ValoresSlider.Value;
                        plot(app.UIAxes,v,'color',app.Grafica.Color);
                        hold(app.UIAxes,'on');
                        v1=d/app.ValoresSlider_2.Value;
                        plot(app.UIAxes,v1,'color',app.Grafica2.Color);
                        hold (app.UIAxes,'off');
                        legend(app.UIAxes,{'y1=Humano', 'y2=Elefante'},'Location','southeast');
                case 'Aceleracion Elefante'
                         d=0:1:100;
                        v=d/app.ValoresSlider.Value;
                        plot(app.UIAxes,v,'color',app.Grafica.Color);
                        hold(app.UIAxes,'on');
                        v1=d/app.ValoresSlider_2.Value;
                        plot(app.UIAxes,v1,'color',app.Grafica2.Color);
                        hold (app.UIAxes,'off');
                        legend(app.UIAxes,{'y1=Elefante', 'y2=Humano'},'Location',"southeast");
                otherwise
                    
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ValoresSliderLabel = uilabel(app.UIFigure);
            app.ValoresSliderLabel.Text = 'Valores';
            app.ValoresSlider = uislider(app.UIFigure);
            app.ValoresSlider.ValueChangedFcn = createCallbackFcn(app, @ValoresSliderValueChanged, true);
            app.SealesListBoxLabel = uilabel(app.UIFigure);
            app.SealesListBoxLabel.Text = 'Señales';
            app.SealesListBox = uilistbox(app.UIFigure);
            app.SealesListBox.Items = {'Mariposa Monarca', 'Corazon', 'Sonido', 'Temperatura', 'Aceleracion', 'Aceleracion Elefante', 'Ruido', 'Aumento del nivel del mar', 'Radiacion de una temperatura', 'Tangente'};
            app.SealesListBox.ValueChangedFcn = createCallbackFcn(app, @SealesListBoxValueChanged, true);
            app.SealesListBox.Value = 'Mariposa Monarca';
            app.ColordelagraficaDropDownLabel = uilabel(app.UIFigure);
            app.ColordelagraficaDropDownLabel.Text = 'Color de la grafica';
            app.ColordelagraficaDropDown = uidropdown(app.UIFigure);
            app.ColordelagraficaDropDown.Items = {'Azul', 'Rojo', 'Verde', 'Morado', 'Negro', 'Naranja'};
            app.ColordelagraficaDropDown.ValueChangedFcn = createCallbackFcn(app, @ColordelagraficaDropDownValueChanged, true);
            app.ColordelagraficaDropDown.Value = 'Azul';
            app.Image = uiimage(app.UIFigure);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Seleccione color antes de modificar la grafica';
            app.UnirButton = uibutton(app.UIFigure, 'push');
            app.UnirButton.ButtonPushedFcn = createCallbackFcn(app, @UnirButtonPushed, true);
            app.UnirButton.Enable = 'off';
            app.UnirButton.Visible = 'off';
            app.UnirButton.Text = 'Unir';
            app.ValoresSlider_2 = uislider(app.UIFigure);
            app.ValoresSlider_2.Enable = 'off';
            app.ValoresSlider_2.Visible = 'off';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Enable = 'off';
            app.EditField_2.Visible = 'off';
            app.EditField_2.Value = 'Valores';
            app.ColordelagraficaDropDown_2 = uidropdown(app.UIFigure);
            app.ColordelagraficaDropDown_2.Items = {'Azul', 'Rojo', 'Verde', 'Morado', 'Negro', 'Naranja'};
            app.ColordelagraficaDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ColordelagraficaDropDown_2ValueChanged, true);
            app.ColordelagraficaDropDown_2.Enable = 'off';
            app.ColordelagraficaDropDown_2.Visible = 'off';
            app.ColordelagraficaDropDown_2.Value = 'Azul';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Enable = 'off';
            app.EditField_3.Visible = 'off';
            app.EditField_3.Value = 'Color de la grafica';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Enable = 'off';
            app.GraficarButton.Visible = 'off';
            app.GraficarButton.Text = 'Graficar';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Titulo'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Practica1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end